classdef DataViewerTERRA < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    XVariableDropDownLabel        matlab.ui.control.Label
    SourceXLimitsMin              matlab.ui.control.NumericEditField
    XLimitsEditFieldLabel_4       matlab.ui.control.Label
    XVariableDropDown_2Label      matlab.ui.control.Label
    DisplayName4                  matlab.ui.control.EditField
    DisplayName3                  matlab.ui.control.EditField
    DisplayName2                  matlab.ui.control.EditField
    DisplayName1                  matlab.ui.control.EditField
    SourceYLabelEditFieldLabel    matlab.ui.control.Label
    SourceYLabel                  matlab.ui.control.EditField
    SourceXLabelEditFieldLabel    matlab.ui.control.Label
    SourceXLabel                  matlab.ui.control.EditField
    ResetSourceYLimits            matlab.ui.control.Button
    ResetSourceXLimits            matlab.ui.control.Button
    SourceYLimitsMax              matlab.ui.control.NumericEditField
    SourceYLimitsEditFieldLabel   matlab.ui.control.Label
    SourceYLimitsMin              matlab.ui.control.NumericEditField
    SourceXLimitsMax              matlab.ui.control.NumericEditField
    SourceXVarDropDown            matlab.ui.control.DropDown
    ResultYLabel                  matlab.ui.control.EditField
    YLabelEditFieldLabel          matlab.ui.control.Label
    ResultXLabel                  matlab.ui.control.EditField
    XLabelEditFieldLabel          matlab.ui.control.Label
    ResetResultYLimits            matlab.ui.control.Button
    ResetResultXLimits            matlab.ui.control.Button
    ResultYLimitsMax              matlab.ui.control.NumericEditField
    ResultYLimitsMin              matlab.ui.control.NumericEditField
    YLimitsEditFieldLabel         matlab.ui.control.Label
    ResultXLimitsMax              matlab.ui.control.NumericEditField
    XLimitsEditFieldLabel_3       matlab.ui.control.Label
    ResultXLimitsMin              matlab.ui.control.NumericEditField
    XLogSwitchResult              matlab.ui.control.Switch
    ResultXVarDropDown            matlab.ui.control.DropDown
    SaveCurrentPlotButton         matlab.ui.control.Button
    MinorticksSwitch              matlab.ui.control.Switch
    MinorticksSwitchLabel         matlab.ui.control.Label
    VariablenameinlegendlabelSwitch  matlab.ui.control.Switch
    VariablenameinlegendlabelSwitchLabel  matlab.ui.control.Label
    FontSizeEditField             matlab.ui.control.NumericEditField
    FontSizeEditFieldLabel        matlab.ui.control.Label
    MarkerSizeEditField           matlab.ui.control.NumericEditField
    MarkerSizeEditFieldLabel      matlab.ui.control.Label
    AxisLineWidthEditField        matlab.ui.control.NumericEditField
    AxisLineWidthEditFieldLabel   matlab.ui.control.Label
    LineWidthEditField            matlab.ui.control.NumericEditField
    LineWidthEditFieldLabel       matlab.ui.control.Label
    PerSpeciesDisplaySwitch       matlab.ui.control.Switch
    PerSpeciesDisplaySwitchLabel  matlab.ui.control.Label
    PlotTypeSwitch                matlab.ui.control.Switch
    PlotTypeLabel                 matlab.ui.control.Label
    YLogSwitchResult              matlab.ui.control.Switch
    LogScaleSwitchLabel_2         matlab.ui.control.Label
    SourceOptionsLabel            matlab.ui.control.Label
    SourceOptions                 matlab.ui.container.CheckBoxTree
    ResultOptionsLabel            matlab.ui.control.Label
    ResultOptions                 matlab.ui.container.CheckBoxTree
    CaseDisplayNameLabel          matlab.ui.control.Label
    HideButton                    matlab.ui.control.Button
    RemoveButton                  matlab.ui.control.Button
    FilesListBox                  matlab.ui.control.ListBox
    AddButton                     matlab.ui.control.Button
    XLogSwitchSource              matlab.ui.control.Switch
    YLogSwitchSource              matlab.ui.control.Switch
    LogScaleSwitchLabel           matlab.ui.control.Label
    MainPlot                      matlab.ui.control.UIAxes
  end

  
  properties (Access = public)
    caseData            % Structure containing data for all the cases to be plotted
    nCases              % Count for number of cases
    dataOptionNames     % Structure containing the names of the data options that go in the DataOptions tree
    resultTree          % tree node containing result options
    resultSubTree       % tree node containing result sub options 
    sourceTree          % tree node containing source options
    sourceSubTree       % tree node containing source sub options
    resultPlot          % plot object containing everything about the result plot
    sourcePlot          % plot object containing everything about the source plot
    plotType            % 'result' or 'source'
    plotOptions         % containing the line styles, marker styles, and colors used for the various cases and data types
  end
  
  methods (Access = public)
    
    % ---------------------------------------------------------------------
    % Update the visibility of Display Name boxes that appear next to the
    % list of files that have been loaded in 
    % ---------------------------------------------------------------------
    function updateDisplayNameVisibility(app)
      % Begin by setting the label visibility
      if app.nCases == 0
        app.CaseDisplayNameLabel.Visible = 0;
      else
        app.CaseDisplayNameLabel.Visible = 1;
      end
      % Update visibility of DisplayName fields
      fNames = fieldnames(app);
      displayFieldIds = find(contains(fNames,'DisplayName'));
      for i = 1:numel(displayFieldIds)
        fid = displayFieldIds(i);
        nameNum = str2double(strrep(fNames{fid},'DisplayName',''));
        if nameNum > app.nCases
          app.(fNames{fid}).Visible = 0;
        else
          app.(fNames{fid}).Visible = 1;
        end
      end
    end
    
    % ---------------------------------------------------------------------
    % Create options for the data to be plotted using the DataOptions tree
    % ---------------------------------------------------------------------
    function updateDataOptions(app)
      app.clearDataOpts;
      caseFields = fieldnames(app.caseData);
      for i = 1:numel(caseFields)
        cnm = caseFields{i};
        ros = {'result','source'};
        uros = {'Result','Source'};
        for j = 1:numel(ros)
          if isfield(app.caseData.(cnm),ros{j})
            rosFields = fieldnames(app.caseData.(cnm).(ros{j}));
            for r = 1:numel(rosFields)
              rnm = rosFields{r};
              treeField = sprintf('%sTree',ros{j});
              subTreeField = sprintf('%sSubTree',ros{j});
              optsField = sprintf('%sOptions',uros{j});
              plotField = sprintf('%sPlot',ros{j});
              % Add result/source fields if not already there
              if ~isfield(app.(treeField), rnm)
                rData = app.caseData.(cnm).(ros{j}).(rnm);
                if ~isstruct(rData) && ~any(rData)
                  continue;
                end
                app.(treeField).(rnm) = uitreenode(app.(optsField));
                app.(treeField).(rnm).Text = rnm;
                app.(subTreeField).(rnm) = struct();
              end
              % Add sub-fields if not already there
              if isstruct(app.caseData.(cnm).(ros{j}).(rnm))
                rosSubFields = fieldnames(app.caseData.(cnm).(ros{j}).(rnm));
                for rr = 1:numel(rosSubFields)
                  rrnm = rosSubFields{rr};
                  if ~isfield(app.(subTreeField).(rnm),rrnm)
                    if ~any(app.caseData.(cnm).(ros{j}).(rnm).(rrnm))
                      continue;
                    end
                    app.(subTreeField).(rnm).(rrnm) = uitreenode(app.(treeField).(rnm));
                    app.(subTreeField).(rnm).(rrnm).Text = rrnm;
                  end
                end
              end
            end
            % Update the x variable dropdown options (t or d only)
            xOptions = {'t','x'};
            nXOpts = numel(xOptions);
            xDropDownField = sprintf('%sXVarDropDown',uros{j});
            for o = 1:nXOpts
              if isfield(app.caseData.(cnm).result,xOptions{o}) && ...
               ~any(contains(app.(xDropDownField).Items,xOptions{o}))
                app.(xDropDownField).Items{end+1} = xOptions{o};
              end
            end
            app.(plotField).xVar = app.(xDropDownField).Value;
          end
        end
      end
    end
    
    % ---------------------------------------------------------------------
    % Clear structures in preparation for reading the X and Y variable
    % options from the case files
    % ---------------------------------------------------------------------
    function clearDataOpts(app)
      app.resultTree = struct();
      app.sourceTree = struct();
      app.resultSubTree = struct();
      app.sourceSubTree = struct();
      delete(app.ResultOptions.Children);
      delete(app.SourceOptions.Children);
      app.ResultXVarDropDown.Items = {};
      app.ResultXVarDropDown.Value = {};
      app.SourceXVarDropDown.Items = {};
      app.SourceXVarDropDown.Value = {};
    end
    
    % ---------------------------------------------------------------------
    % Update (pData).yVarNames with the list of Y variables to plot for the
    % currently selected type of plot (result or source)
    % ---------------------------------------------------------------------
    function updateYDataSelection(app)
      % Extract which y data to update
      pField = app.getDataFieldName('Plot');
      optField = app.getObjectFieldName('Options');
      % Clear the previous entries to yVarNames
      app.(pField).yVarNames = struct();
      % Extract list of checked nodes and loop through list, copying the
      % values over to pField.yVarNames to be accessed during plotting
      checkedNodes = app.(optField).CheckedNodes;
      for n = 1:numel(checkedNodes)
        nnm = checkedNodes(n).Text;
        if strcmp(checkedNodes(n).Parent.Type,'uicheckboxtree')
          app.(pField).yVarNames.(nnm) = struct();
          app.(pField).yVarNames.(nnm).Children = {};
        elseif strcmp(checkedNodes(n).Parent.Type,'uitreenode')
          pnm = checkedNodes(n).Parent.Text;
          if ~isfield(app.(pField).yVarNames,pnm)
            app.(pField).yVarNames.(pnm) = struct();
            app.(pField).yVarNames.(pnm).Children = {};
          end
          app.(pField).yVarNames.(pnm).Children{end+1} = nnm;
        end
      end
      
      % Assign line styles to each of the yVarNames 
      yFieldNames = fieldnames(app.(pField).yVarNames);
      cid = 1;
      for n = 1:numel(yFieldNames)
        yfn = yFieldNames{n};
        children = app.(pField).yVarNames.(yfn).Children;
        app.(pField).yVarNames.(yfn).Colors = {};
        if isempty(children)
          app.(pField).yVarNames.(yfn).Colors{1} = app.plotOptions.colors(cid);
          cid = app.updateColorID(cid);
        else
          for c = 1:numel(children)
            app.(pField).yVarNames.(yfn).Colors{c} = app.plotOptions.colors(cid);
            cid = app.updateColorID(cid);
          end
        end
      end
    end
    
    function colorID = updateColorID(app,colorID)
      if colorID == numel(app.plotOptions.colors)
        colorID = 1;
      else
        colorID = colorID + 1;
      end
    end
    
    % ---------------------------------------------------------------------
    % Initialize fields for the resultPlot and sourcePlot structures
    % ---------------------------------------------------------------------
    function initPlot(app,pField)
      app.(pField) = struct();
      app.(pField).xScale = 'linear';
      app.(pField).yScale = 'linear';
      app.(pField).xLim  = [0, 1];
      app.(pField).yLim  = [0, 1];
      app.(pField).xLabel = 'X';
      app.(pField).yLabel = 'Y';
    end
    
    % ---------------------------------------------------------------------
    % Initialize plot options and set values in the relevant user entry
    % field if necessary for that value
    % ---------------------------------------------------------------------
    function initPlotOptions(app)
      app.plotOptions = struct();
      % Plot line width
      app.plotOptions.lineWidth = 1.5;
      app.LineWidthEditField.Value = app.plotOptions.lineWidth;
      % Marker size
      app.plotOptions.markerSize = 8;
      app.MarkerSizeEditField.Value = app.plotOptions.markerSize;
      % Axis line width
      app.plotOptions.axisLineWidth = 1.0;
      app.AxisLineWidthEditField.Value = app.plotOptions.axisLineWidth;
      app.MainPlot.LineWidth = app.plotOptions.axisLineWidth;
      % Font size
      app.plotOptions.fontSize = 22;
      app.FontSizeEditField.Value = app.plotOptions.fontSize;
      app.MainPlot.FontSize = app.plotOptions.fontSize;
      % Various line style inputs
      app.plotOptions.colors = {'r','b','g','m','k',...
        [0, 0.4470, 0.7410],[0.8500, 0.3250, 0.0980],[0.9290, 0.6940, 0.1250],...
        [0.4940, 0.1840, 0.5560],[0.4660, 0.6740, 0.1880],[0.3010, 0.7450, 0.9330],...
        [0.6350, 0.0780, 0.1840]};
      app.plotOptions.lineStyles = {'-','-.','--',':'};
      app.plotOptions.markerStyles = {'none','o','s','v','>','<','^'};
      app.updateMinorTicks;
    end
    
    function updatePlotOptions(app)
      % Plot line width
      app.plotOptions.lineWidth = app.LineWidthEditField.Value;
      app.MainPlot.LineWidth = app.LineWidthEditField.Value;
      % Axis line width
      app.plotOptions.axisLineWidth = app.AxisLineWidthEditField.Value;
      app.MainPlot.LineWidth = app.AxisLineWidthEditField.Value;
      % Font size
      app.plotOptions.fontSize = app.FontSizeEditField.Value;
      % Marker size
      app.plotOptions.markerSize = app.MarkerSizeEditField.Value;
    end
    
    % ---------------------------------------------------------------------
    % Get structure fieldname for data, lowercase 'result%s' or 'source%s'
    % ---------------------------------------------------------------------
    function dataFieldName = getDataFieldName(app,dataType)
      pType = app.PlotTypeSwitch.Value;
      if strcmp(pType,'Result')
        dataFieldName = sprintf('result%s',dataType);
      elseif strcmp(pType,'Source')
        dataFieldName = sprintf('source%s',dataType);
      end
    end
    
    % ---------------------------------------------------------------------
    % Get structure fieldname for app object, uppercase 'Result%s' or
    % %'Source%s'
    % ---------------------------------------------------------------------
    function objFieldName = getObjectFieldName(app,objType)
      pType = app.PlotTypeSwitch.Value;
      if strcmp(pType,'Result')
        objFieldName = sprintf('Result%s',objType);
      elseif strcmp(pType,'Source')
        objFieldName = sprintf('Source%s',objType);
      end
    end
    
    function pField = getPlotField(app)
      if strcmp(app.PlotTypeSwitch.Value,'Result')
        pField = 'resultPlot';
      elseif strcmp(app.PlotTypeSwitch.Value,'Source')
        pField = 'sourcePlot';
      end
    end
    
    % ---------------------------------------------------------------------
    % Get the data type being read from the case data
    % ---------------------------------------------------------------------
    function cDataType = getCaseDataType(app)
      if strcmp(app.PlotTypeSwitch.Value,'Result')
        cDataType = 'result';
      elseif strcmp(app.PlotTypeSwitch.Value,'Source')
        cDataType = 'source';
      end
    end
    
    % ---------------------------------------------------------------------
    % Update axis limits based on user input
    % ---------------------------------------------------------------------
    function updateAxisLimits(app)
      pFieldNames = {'resultPlot','sourcePlot'};
      plotTypeTest = {'Result','Source'};
      for p = 1:numel(pFieldNames)
        pfn = pFieldNames{p};
        xMin = app.(app.getObjectFieldName('XLimitsMin')).Value;
        xMax = app.(app.getObjectFieldName('XLimitsMax')).Value;
        yMin = app.(app.getObjectFieldName('YLimitsMin')).Value;
        yMax = app.(app.getObjectFieldName('YLimitsMax')).Value;
        app.(pfn).xLim = [xMin, xMax];
        app.(pfn).yLim = [yMin, yMax];
        if strcmp(app.PlotTypeSwitch.Value,plotTypeTest{p})
          if (~isempty(xMin) && ~isempty(xMax)) && (xMin < xMax)
            app.MainPlot.XLim = [xMin, xMax];
          end
          if (~isempty(yMin) && ~isempty(yMax)) && (yMin < yMax)
            app.MainPlot.YLim = [yMin, yMax];
          end
        end
      end
    end
    
    % ---------------------------------------------------------------------
    % Update axis labels based on user input
    % ---------------------------------------------------------------------
    function updateAxisLabels(app)
      ros = {'result','source'};
      uros = {'Result','Source'};
      for j = 1:numel(ros)
        xLabelField = sprintf('%sXLabel',uros{j});
        yLabelField = sprintf('%sYLabel',uros{j});
        plotField = sprintf('%sPlot',ros{j});
        app.(plotField).xLabel = app.(xLabelField).Value;
        app.(plotField).yLabel = app.(yLabelField).Value;
        if strcmp(app.PlotTypeSwitch.Value,uros{j})
          app.MainPlot.XLabel.String = app.(xLabelField).Value;
          app.MainPlot.YLabel.String = app.(yLabelField).Value;
        end
      end
    end
    
    % ---------------------------------------------------------------------
    % Update axis scales based on user input
    % ---------------------------------------------------------------------
    function updateAxisScale(app)
      ros = {'result','source'};
      uros = {'Result','Source'};
      for j = 1:numel(ros)
        plotField = sprintf('%sPlot',ros{j});
        xSwitchField = sprintf('XLogSwitch%s',uros{j});
        ySwitchField = sprintf('YLogSwitch%s',uros{j});
        xValue = app.(xSwitchField).Value;
        yValue = app.(ySwitchField).Value;
        if strcmp(xValue,'')
          xScale = 'log';
        elseif strcmp(xValue,'X-Axis')
          xScale = 'linear';
        end
        if strcmp(yValue,'')
          yScale = 'log';
        elseif strcmp(yValue,'Y-Axis')
          yScale = 'linear';
        end
        app.(plotField).xScale = xScale;
        app.(plotField).yScale = yScale;
        if strcmp(app.PlotTypeSwitch.Value,uros{j})
          app.MainPlot.XScale = xScale;
          app.MainPlot.YScale = yScale;
        end
      end
    end
    
    % ---------------------------------------------------------------------
    % Construct legend label 
    % ---------------------------------------------------------------------
    function legendLabel = getLegendLabel(app,varLabel,caseID)
      plotVarLabelYN = app.VariablenameinlegendlabelSwitch.Value;
      caseDisplayName = app.(sprintf('DisplayName%d',caseID)).Value;
      if strcmp(plotVarLabelYN,'Yes')
        legendLabel = sprintf('%s - (%s)',caseDisplayName,varLabel);
      elseif strcmp(plotVarLabelYN,'No')
        legendLabel = sprintf('%s',caseDisplayName);
      end
    end
    
    % ---------------------------------------------------------------------
    % Update minor tick on/off
    % ---------------------------------------------------------------------
    function updateMinorTicks(app)
      if strcmp(app.MinorticksSwitch.Value, 'Yes')
        minorSet = 'on';
      elseif strcmp(app.MinorticksSwitch.Value,'No')
        minorSet = 'off';
      end
      app.MainPlot.XMinorTick = minorSet;
      app.MainPlot.YMinorTick = minorSet;
    end
    
    % ---------------------------------------------------------------------
    % Update app.MainPlot
    % ---------------------------------------------------------------------
    function updatePlot(app)
      % Extract plot options
      lineWidth = app.plotOptions.lineWidth;
      markerSize = app.plotOptions.markerSize;
      lineStyles = app.plotOptions.lineStyles;
      % Clear the plot in preparation for re-plotting everything 
      cla(app.MainPlot);
      % Update pData.yVarNames from the currently checked options
      app.updateYDataSelection;
      % Save a bunch of handy field names and structure fields 
      pField = app.getDataFieldName('Plot');
      typeField = app.getDataFieldName('');
      cDataType = app.getCaseDataType;
      pData = app.(pField);
      caseFields = fieldnames(app.caseData);
      % Loop over each case for the requested data
      for c = 1:numel(caseFields)
        cDataStruct = app.caseData.(caseFields{c});
        labels = cDataStruct.labels;
        if ~isfield(cDataStruct,cDataType)
          continue;
        end
        cDataStruct = cDataStruct.(cDataType);
        if ~isfield(cDataStruct,pData.xVar)
          continue;
        end
        xData = cDataStruct.(pData.xVar);
        yFields = fieldnames(pData.yVarNames);
        hold(app.MainPlot,'on')
        % Loop over selected y data fields
        for y = 1:numel(yFields)
          yfn = yFields{y};
          color = pData.yVarNames.(yfn).Colors;
          if ~isfield(labels.(typeField), yfn)
            continue
          end
          if isempty(pData.yVarNames.(yfn).Children)
            yData = cDataStruct.(yfn);
            legendLabel = app.getLegendLabel(labels.(typeField).(yfn),c);
            plot(app.MainPlot,xData,yData,'LineWidth',lineWidth,...
                'MarkerSize',markerSize,'Color',color{1}{1},...
                'DisplayName',legendLabel,'LineStyle',lineStyles{c});
          else
            ySubFields = pData.yVarNames.(yfn).Children;
            for yy = 1:numel(ySubFields)
              yyfn = ySubFields{yy};
              if ~isfield(labels.(typeField).(yfn), yyfn)
                continue
              end
              if strcmp(app.PerSpeciesDisplaySwitch.Value,'Full')
                legendLabel = app.getLegendLabel(labels.(typeField).(yfn).(yyfn),c);
              elseif strcmp(app.PerSpeciesDisplaySwitch.Value,'Spnm')
                legendLabel = app.getLegendLabel(ySubFields{yy},c);
              end
              yData = cDataStruct.(yfn).(yyfn);
              plot(app.MainPlot,xData,yData,'LineWidth',lineWidth,...
                'MarkerSize',markerSize,'DisplayName',legendLabel,...
                'Color',color{yy}{1},'LineStyle',lineStyles{c});
            end
          end
        end
      end
      app.MainPlot.XScale = pData.xScale;
      app.MainPlot.YScale = pData.yScale;
      app.MainPlot.XLabel.String = pData.xLabel;
      app.MainPlot.YLabel.String = pData.yLabel;
      app.MainPlot.FontSize = app.plotOptions.fontSize;
      app.updateAxisLimits;
      app.updateMinorTicks;
      legend(app.MainPlot);
      hold(app.MainPlot,'off')
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      app.nCases = 0;
      app.updateDisplayNameVisibility;
      app.clearDataOpts;
      app.initPlot('sourcePlot')
      app.initPlot('resultPlot')
      app.initPlotOptions;
    end

    % Button pushed function: AddButton
    function AddButtonPushed(app, event)
      [caseFile, casePath] = uigetfile('*.mat');
      if isequal(caseFile,0)
        return
      end
      app.nCases = app.nCases + 1;
      caseName = matlab.lang.makeValidName(strrep(caseFile,'.mat',''));
      app.caseData.(caseName) = load(sprintf('%s%s',casePath,caseFile));
      app.FilesListBox.Items{app.nCases} = caseName;
      app.updateDisplayNameVisibility;
      app.updateDataOptions;
      app.updateYDataSelection;
    end

    % Value changed function: ResultXVarDropDown
    function ResultXVarDropDownValueChanged(app, event)
      app.resultPlot.xVar = app.ResultXVarDropDown.Value;
      if strcmp(app.ResultXVarDropDown.Value, 't')
        app.resultPlot.xLabel = 't, \mus';
      elseif strcmp(app.ResultXVarDropDown.Value, 'x')
        app.resultPlot.xLabel = 'x, cm';
      end
      app.updatePlot;
    end

    % Value changed function: YLogSwitchResult
    function YLogSwitchResultValueChanged(app, event)
      value = app.YLogSwitchResult.Value;
      pField = app.getPlotField;
      if strcmp(pField,'resultPlot')
        if strcmp(value,'')
          yScale = 'log';
        elseif strcmp(value,'Y-Axis')
          yScale = 'linear';         
        end
        app.MainPlot.YScale = yScale;
      end
      app.(pField).yScale = yScale;
    end

    % Value changed function: XLogSwitchResult
    function XLogSwitchResultValueChanged(app, event)
      app.updateAxisScale;
    end

    % Callback function: ResultOptions
    function ResultOptionsCheckedNodesChanged(app, event)
      app.updatePlot;
    end

    % Button pushed function: ResetResultYLimits
    function ResetResultYLimitsButtonPushed(app, event)
      if strcmp(app.PlotTypeSwitch.Value,'Result')
        ylim(app.MainPlot,'auto')
        app.ResultYLimitsMin.Value = 0;
        app.ResultYLimitsMax.Value = 0;
        app.resultPlot.yLim = app.MainPlot.YLim;
      end
    end

    % Button pushed function: ResetResultXLimits
    function ResetResultXLimitsButtonPushed(app, event)
      if strcmp(app.PlotTypeSwitch.Value,'Result')
        xlim(app.MainPlot,'auto')
        app.ResultXLimitsMin.Value = 0;
        app.ResultXLimitsMax.Value = 0;
        app.resultPlot.xLim = app.MainPlot.XLim;
      end
    end

    % Value changed function: ResultYLimitsMin
    function ResultYLimitsMinValueChanged(app, event)
      app.updateAxisLimits;
    end

    % Value changed function: ResultYLimitsMax
    function ResultYLimitsMaxValueChanged(app, event)
      app.updateAxisLimits;
    end

    % Value changed function: ResultXLimitsMax
    function ResultXLimitsMaxValueChanged(app, event)
      app.updateAxisLimits;
    end

    % Value changed function: ResultXLimitsMin
    function ResultXLimitsMinValueChanged(app, event)
      app.updateAxisLimits;
    end

    % Value changed function: ResultXLabel
    function ResultXLabelValueChanged(app, event)
      app.updateAxisLabels;
    end

    % Value changed function: ResultYLabel
    function ResultYLabelValueChanged(app, event)
      app.updateAxisLabels;
    end

    % Value changing function: ResultXLabel
    function ResultXLabelValueChanging(app, event)
      warning('off')
      app.updateAxisLabels;
      warning('on')
    end

    % Value changing function: ResultYLabel
    function ResultYLabelValueChanging(app, event)
      warning('off')
      app.updateAxisLabels;
      warning('on')
    end

    % Value changed function: LineWidthEditField
    function LineWidthEditFieldValueChanged(app, event)
      app.updatePlotOptions;
      app.updatePlot;
    end

    % Value changed function: AxisLineWidthEditField
    function AxisLineWidthEditFieldValueChanged(app, event)
      app.updatePlotOptions;
    end

    % Value changed function: MarkerSizeEditField
    function MarkerSizeEditFieldValueChanged(app, event)
      app.updatePlotOptions;
      app.updatePlot;
    end

    % Value changed function: FontSizeEditField
    function FontSizeEditFieldValueChanged(app, event)
      app.updatePlotOptions;
      app.MainPlot.FontSize = app.plotOptions.fontSize;
    end

    % Value changed function: PerSpeciesDisplaySwitch
    function PerSpeciesDisplaySwitchValueChanged(app, event)
      app.updatePlot;
    end

    % Value changed function: SourceXLabel
    function SourceXLabelValueChanged(app, event)
      app.updateAxisLabels;
    end

    % Value changing function: SourceXLabel
    function SourceXLabelValueChanging(app, event)
      warning('off')
      app.updateAxisLabels;
      warning('on')
    end

    % Value changed function: SourceYLabel
    function SourceYLabelValueChanged(app, event)
      app.updateAxisLabels;
    end

    % Value changing function: SourceYLabel
    function SourceYLabelValueChanging(app, event)
      warning('off')
      app.updateAxisLabels;
      warning('on')
    end

    % Button pushed function: ResetSourceXLimits
    function ResetSourceXLimitsButtonPushed(app, event)
      if strcmp(app.PlotTypeSwitch.Value,'Source')
        xlim(app.MainPlot,'auto')
        app.SourceXLimitsMin.Value = 0;
        app.SourceXLimitsMax.Value = 0;
        app.sourcePlot.xLim = app.MainPlot.XLim;
      end
    end

    % Button pushed function: ResetSourceYLimits
    function ResetSourceYLimitsButtonPushed(app, event)
      if strcmp(app.PlotTypeSwitch.Value,'Source')
        ylim(app.MainPlot,'auto')
        app.SourceYLimitsMin.Value = 0;
        app.SourceYLimitsMax.Value = 0;
        app.sourcePlot.yLim = app.MainPlot.YLim;
      end
    end

    % Callback function: SourceOptions
    function SourceOptionsCheckedNodesChanged(app, event)
      app.updatePlot;
    end

    % Value changed function: SourceXVarDropDown
    function SourceXVarDropDownValueChanged(app, event)
      app.sourcePlot.xVar = app.SourceXVarDropDown.Value;
      if strcmp(app.SourceXVarDropDown.Value, 't')
        app.sourcePlot.xLabel = 't, \mus';
      elseif strcmp(app.SourceXVarDropDown.Value, 'x')
        app.sourcePlot.xLabel = 'x, cm';
      end
      app.updatePlot;
    end

    % Value changed function: XLogSwitchSource
    function XLogSwitchSourceValueChanged(app, event)
      app.updateAxisScale;
    end

    % Value changed function: YLogSwitchSource
    function YLogSwitchSourceValueChanged(app, event)
      app.updateAxisScale;
    end

    % Value changed function: PlotTypeSwitch
    function PlotTypeSwitchValueChanged(app, event)
      app.updatePlot;
    end

    % Value changed function: SourceXLimitsMin
    function SourceXLimitsMinValueChanged(app, event)
      app.updateAxisLimits;
    end

    % Value changed function: SourceXLimitsMax
    function SourceXLimitsMaxValueChanged(app, event)
      app.updateAxisLimits;
    end

    % Value changed function: SourceYLimitsMin
    function SourceYLimitsMinValueChanged(app, event)
      app.updateAxisLimits;
    end

    % Value changed function: SourceYLimitsMax
    function SourceYLimitsMaxValueChanged(app, event)
      app.updateAxisLimits;
    end

    % Button pushed function: RemoveButton
    function RemoveButtonPushed(app, event)
      % Remove the case from the caseData struct
      caseName = app.FilesListBox.Value;
      app.caseData = rmfield(app.caseData,caseName);
      % Remove the selected case from the list of cases
      caseID = find(strcmp(app.FilesListBox.Items,caseName));
      newItems = cell(app.nCases-1,1);
      for i = 1:app.nCases-1
        if i < caseID 
          newItems{i} = app.FilesListBox.Items{i};
        else
          newItems{i} = app.FilesListBox.Items{i+1};
        end
      end
      app.FilesListBox.Items = newItems;
      % Update the displays and data structures
      app.nCases = app.nCases - 1;
      app.updateDisplayNameVisibility;
      app.updateDataOptions;
      app.updateYDataSelection;
      app.updatePlot;
    end

    % Value changed function: VariablenameinlegendlabelSwitch
    function VariablenameinlegendlabelSwitchValueChanged(app, event)
      app.updatePlot;
    end

    % Value changed function: DisplayName1
    function DisplayName1ValueChanged(app, event)
      app.updatePlot;
    end

    % Value changed function: DisplayName2
    function DisplayName2ValueChanged(app, event)
      app.updatePlot;
    end

    % Value changed function: DisplayName3
    function DisplayName3ValueChanged(app, event)
      app.updatePlot;
    end

    % Value changed function: DisplayName4
    function DisplayName4ValueChanged(app, event)
      app.updatePlot;
    end

    % Value changed function: MinorticksSwitch
    function MinorticksSwitchValueChanged(app, event)
      app.updateMinorTicks;
    end

    % Button pushed function: SaveCurrentPlotButton
    function SaveCurrentPlotButtonPushed(app, event)
      fig = figure('Position',[746 288 783 588]);
      fig.Visible = 'off';
      copyUIAxes(app.MainPlot,fig,"legend",app.MainPlot.Legend);
      % Get the filename from user prompt
      fileName = inputdlg({'File name:'},'Save Plot');
      if isempty(fileName) || isempty(fileName{1})
        return;
      end
      fileName = matlab.lang.makeValidName(fileName{1});
      % Save as png and fig files.
      saveas(fig, fileName, 'png');
      savefig(fig, fileName);
      % Delete the temporary figure.
      delete(fig);
    end

    % Selection changed function: ResultOptions
    function ResultOptionsSelectionChanged(app, event)
      app.updatePlot;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.MainPlot = uiaxes(app.UIFigure);
      xlabel(app.MainPlot, 'X')
      ylabel(app.MainPlot, 'Y')
      zlabel(app.MainPlot, 'Z')
      app.LogScaleSwitchLabel = uilabel(app.UIFigure);
      app.LogScaleSwitchLabel.Text = 'Log Scale';
      app.YLogSwitchSource = uiswitch(app.UIFigure, 'slider');
      app.YLogSwitchSource.Items = {'Y-Axis', ''};
      app.YLogSwitchSource.ValueChangedFcn = createCallbackFcn(app, @YLogSwitchSourceValueChanged, true);
      app.YLogSwitchSource.Value = 'Y-Axis';
      app.XLogSwitchSource = uiswitch(app.UIFigure, 'slider');
      app.XLogSwitchSource.Items = {'X-Axis', ''};
      app.XLogSwitchSource.ValueChangedFcn = createCallbackFcn(app, @XLogSwitchSourceValueChanged, true);
      app.XLogSwitchSource.Value = 'X-Axis';
      app.AddButton = uibutton(app.UIFigure, 'push');
      app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
      app.AddButton.Text = 'Add';
      app.FilesListBox = uilistbox(app.UIFigure);
      app.FilesListBox.Items = {'Select a File'};
      app.FilesListBox.Value = 'Select a File';
      app.RemoveButton = uibutton(app.UIFigure, 'push');
      app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
      app.RemoveButton.Text = 'Remove';
      app.HideButton = uibutton(app.UIFigure, 'push');
      app.HideButton.Text = 'Hide';
      app.CaseDisplayNameLabel = uilabel(app.UIFigure);
      app.CaseDisplayNameLabel.Text = 'Case Display Name';
      app.ResultOptions = uitree(app.UIFigure, 'checkbox');
      app.ResultOptions.SelectionChangedFcn = createCallbackFcn(app, @ResultOptionsSelectionChanged, true);
      % Assign Checked Nodes
      app.ResultOptions.CheckedNodesChangedFcn = createCallbackFcn(app, @ResultOptionsCheckedNodesChanged, true);
      app.ResultOptionsLabel = uilabel(app.UIFigure);
      app.ResultOptionsLabel.Text = 'Result Options';
      app.SourceOptions = uitree(app.UIFigure, 'checkbox');
      % Assign Checked Nodes
      app.SourceOptions.CheckedNodesChangedFcn = createCallbackFcn(app, @SourceOptionsCheckedNodesChanged, true);
      app.SourceOptionsLabel = uilabel(app.UIFigure);
      app.SourceOptionsLabel.Text = 'Source Options';
      app.LogScaleSwitchLabel_2 = uilabel(app.UIFigure);
      app.LogScaleSwitchLabel_2.Text = 'Log Scale';
      app.YLogSwitchResult = uiswitch(app.UIFigure, 'slider');
      app.YLogSwitchResult.Items = {'Y-Axis', ''};
      app.YLogSwitchResult.ValueChangedFcn = createCallbackFcn(app, @YLogSwitchResultValueChanged, true);
      app.YLogSwitchResult.Value = 'Y-Axis';
      app.PlotTypeLabel = uilabel(app.UIFigure);
      app.PlotTypeLabel.Text = 'Plot Type';
      app.PlotTypeSwitch = uiswitch(app.UIFigure, 'slider');
      app.PlotTypeSwitch.Items = {'Result', 'Source'};
      app.PlotTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotTypeSwitchValueChanged, true);
      app.PlotTypeSwitch.Value = 'Result';
      app.PerSpeciesDisplaySwitchLabel = uilabel(app.UIFigure);
      app.PerSpeciesDisplaySwitchLabel.Text = 'Per-Species Display';
      app.PerSpeciesDisplaySwitch = uiswitch(app.UIFigure, 'slider');
      app.PerSpeciesDisplaySwitch.Items = {'Full', 'Spnm'};
      app.PerSpeciesDisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @PerSpeciesDisplaySwitchValueChanged, true);
      app.PerSpeciesDisplaySwitch.Value = 'Full';
      app.LineWidthEditFieldLabel = uilabel(app.UIFigure);
      app.LineWidthEditFieldLabel.Text = 'Line Width';
      app.LineWidthEditField = uieditfield(app.UIFigure, 'numeric');
      app.LineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @LineWidthEditFieldValueChanged, true);
      app.AxisLineWidthEditFieldLabel = uilabel(app.UIFigure);
      app.AxisLineWidthEditFieldLabel.Text = 'Axis Line Width';
      app.AxisLineWidthEditField = uieditfield(app.UIFigure, 'numeric');
      app.AxisLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @AxisLineWidthEditFieldValueChanged, true);
      app.MarkerSizeEditFieldLabel = uilabel(app.UIFigure);
      app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
      app.MarkerSizeEditField = uieditfield(app.UIFigure, 'numeric');
      app.MarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeEditFieldValueChanged, true);
      app.FontSizeEditFieldLabel = uilabel(app.UIFigure);
      app.FontSizeEditFieldLabel.Text = 'Font Size';
      app.FontSizeEditField = uieditfield(app.UIFigure, 'numeric');
      app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FontSizeEditFieldValueChanged, true);
      app.VariablenameinlegendlabelSwitchLabel = uilabel(app.UIFigure);
      app.VariablenameinlegendlabelSwitchLabel.Text = 'Variable name in legend label';
      app.VariablenameinlegendlabelSwitch = uiswitch(app.UIFigure, 'slider');
      app.VariablenameinlegendlabelSwitch.Items = {'Yes', 'No'};
      app.VariablenameinlegendlabelSwitch.ValueChangedFcn = createCallbackFcn(app, @VariablenameinlegendlabelSwitchValueChanged, true);
      app.VariablenameinlegendlabelSwitch.Value = 'Yes';
      app.MinorticksSwitchLabel = uilabel(app.UIFigure);
      app.MinorticksSwitchLabel.Text = 'Minor ticks';
      app.MinorticksSwitch = uiswitch(app.UIFigure, 'slider');
      app.MinorticksSwitch.Items = {'Yes', 'No'};
      app.MinorticksSwitch.ValueChangedFcn = createCallbackFcn(app, @MinorticksSwitchValueChanged, true);
      app.MinorticksSwitch.Value = 'Yes';
      app.SaveCurrentPlotButton = uibutton(app.UIFigure, 'push');
      app.SaveCurrentPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentPlotButtonPushed, true);
      app.SaveCurrentPlotButton.Text = 'Save Current Plot';
      app.ResultXVarDropDown = uidropdown(app.UIFigure);
      app.ResultXVarDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultXVarDropDownValueChanged, true);
      app.XLogSwitchResult = uiswitch(app.UIFigure, 'slider');
      app.XLogSwitchResult.Items = {'X-Axis', ''};
      app.XLogSwitchResult.ValueChangedFcn = createCallbackFcn(app, @XLogSwitchResultValueChanged, true);
      app.XLogSwitchResult.Value = 'X-Axis';
      app.ResultXLimitsMin = uieditfield(app.UIFigure, 'numeric');
      app.ResultXLimitsMin.ValueChangedFcn = createCallbackFcn(app, @ResultXLimitsMinValueChanged, true);
      app.XLimitsEditFieldLabel_3 = uilabel(app.UIFigure);
      app.XLimitsEditFieldLabel_3.Text = 'X Limits';
      app.ResultXLimitsMax = uieditfield(app.UIFigure, 'numeric');
      app.ResultXLimitsMax.ValueChangedFcn = createCallbackFcn(app, @ResultXLimitsMaxValueChanged, true);
      app.YLimitsEditFieldLabel = uilabel(app.UIFigure);
      app.YLimitsEditFieldLabel.Text = 'Y Limits';
      app.ResultYLimitsMin = uieditfield(app.UIFigure, 'numeric');
      app.ResultYLimitsMin.ValueChangedFcn = createCallbackFcn(app, @ResultYLimitsMinValueChanged, true);
      app.ResultYLimitsMax = uieditfield(app.UIFigure, 'numeric');
      app.ResultYLimitsMax.ValueChangedFcn = createCallbackFcn(app, @ResultYLimitsMaxValueChanged, true);
      app.ResetResultXLimits = uibutton(app.UIFigure, 'push');
      app.ResetResultXLimits.ButtonPushedFcn = createCallbackFcn(app, @ResetResultXLimitsButtonPushed, true);
      app.ResetResultXLimits.Text = 'Reset X Limits';
      app.ResetResultYLimits = uibutton(app.UIFigure, 'push');
      app.ResetResultYLimits.ButtonPushedFcn = createCallbackFcn(app, @ResetResultYLimitsButtonPushed, true);
      app.ResetResultYLimits.Text = 'Reset Y Limits';
      app.XLabelEditFieldLabel = uilabel(app.UIFigure);
      app.XLabelEditFieldLabel.Text = 'X Label';
      app.ResultXLabel = uieditfield(app.UIFigure, 'text');
      app.ResultXLabel.ValueChangedFcn = createCallbackFcn(app, @ResultXLabelValueChanged, true);
      app.ResultXLabel.ValueChangingFcn = createCallbackFcn(app, @ResultXLabelValueChanging, true);
      app.YLabelEditFieldLabel = uilabel(app.UIFigure);
      app.YLabelEditFieldLabel.Text = 'Y Label';
      app.ResultYLabel = uieditfield(app.UIFigure, 'text');
      app.ResultYLabel.ValueChangedFcn = createCallbackFcn(app, @ResultYLabelValueChanged, true);
      app.ResultYLabel.ValueChangingFcn = createCallbackFcn(app, @ResultYLabelValueChanging, true);
      app.SourceXVarDropDown = uidropdown(app.UIFigure);
      app.SourceXVarDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceXVarDropDownValueChanged, true);
      app.SourceXLimitsMax = uieditfield(app.UIFigure, 'numeric');
      app.SourceXLimitsMax.ValueChangedFcn = createCallbackFcn(app, @SourceXLimitsMaxValueChanged, true);
      app.SourceYLimitsMin = uieditfield(app.UIFigure, 'numeric');
      app.SourceYLimitsMin.ValueChangedFcn = createCallbackFcn(app, @SourceYLimitsMinValueChanged, true);
      app.SourceYLimitsEditFieldLabel = uilabel(app.UIFigure);
      app.SourceYLimitsEditFieldLabel.Text = 'Y Limits';
      app.SourceYLimitsMax = uieditfield(app.UIFigure, 'numeric');
      app.SourceYLimitsMax.ValueChangedFcn = createCallbackFcn(app, @SourceYLimitsMaxValueChanged, true);
      app.ResetSourceXLimits = uibutton(app.UIFigure, 'push');
      app.ResetSourceXLimits.ButtonPushedFcn = createCallbackFcn(app, @ResetSourceXLimitsButtonPushed, true);
      app.ResetSourceXLimits.Text = 'Reset X Limits';
      app.ResetSourceYLimits = uibutton(app.UIFigure, 'push');
      app.ResetSourceYLimits.ButtonPushedFcn = createCallbackFcn(app, @ResetSourceYLimitsButtonPushed, true);
      app.ResetSourceYLimits.Text = 'Reset Y Limits';
      app.SourceXLabel = uieditfield(app.UIFigure, 'text');
      app.SourceXLabel.ValueChangedFcn = createCallbackFcn(app, @SourceXLabelValueChanged, true);
      app.SourceXLabel.ValueChangingFcn = createCallbackFcn(app, @SourceXLabelValueChanging, true);
      app.SourceXLabelEditFieldLabel = uilabel(app.UIFigure);
      app.SourceXLabelEditFieldLabel.Text = 'X Label';
      app.SourceYLabel = uieditfield(app.UIFigure, 'text');
      app.SourceYLabel.ValueChangedFcn = createCallbackFcn(app, @SourceYLabelValueChanged, true);
      app.SourceYLabel.ValueChangingFcn = createCallbackFcn(app, @SourceYLabelValueChanging, true);
      app.SourceYLabelEditFieldLabel = uilabel(app.UIFigure);
      app.SourceYLabelEditFieldLabel.Text = 'Y Label';
      app.DisplayName1 = uieditfield(app.UIFigure, 'text');
      app.DisplayName1.ValueChangedFcn = createCallbackFcn(app, @DisplayName1ValueChanged, true);
      app.DisplayName2 = uieditfield(app.UIFigure, 'text');
      app.DisplayName2.ValueChangedFcn = createCallbackFcn(app, @DisplayName2ValueChanged, true);
      app.DisplayName3 = uieditfield(app.UIFigure, 'text');
      app.DisplayName3.ValueChangedFcn = createCallbackFcn(app, @DisplayName3ValueChanged, true);
      app.DisplayName4 = uieditfield(app.UIFigure, 'text');
      app.DisplayName4.ValueChangedFcn = createCallbackFcn(app, @DisplayName4ValueChanged, true);
      app.XVariableDropDown_2Label = uilabel(app.UIFigure);
      app.XVariableDropDown_2Label.Text = 'X Variable';
      app.XLimitsEditFieldLabel_4 = uilabel(app.UIFigure);
      app.XLimitsEditFieldLabel_4.Text = 'X Limits';
      app.SourceXLimitsMin = uieditfield(app.UIFigure, 'numeric');
      app.SourceXLimitsMin.ValueChangedFcn = createCallbackFcn(app, @SourceXLimitsMinValueChanged, true);
      app.XVariableDropDownLabel = uilabel(app.UIFigure);
      app.XVariableDropDownLabel.Text = 'X Variable';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DataViewerTERRA

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end